classdef FwdModel_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        dD_chamber_typ_2               matlab.ui.control.DropDown
        UnitsLabel                     matlab.ui.control.Label
        Title                          matlab.ui.control.TextArea
        TabGroup                       matlab.ui.container.TabGroup
        ForwardmodelTab                matlab.ui.container.Tab
        ChamberdesignPanel             matlab.ui.container.Panel
        chamber_name                   matlab.ui.control.EditField
        NameLabel                      matlab.ui.control.Label
        pB_chamber_Save                matlab.ui.control.Button
        pB_chamber_Load                matlab.ui.control.Button
        electrodes                     matlab.ui.container.Panel
        pB_elec_layout_Add_2           matlab.ui.control.Button
        pB_elec_layout_Del             matlab.ui.control.Button
        pB_elec_layout_Add             matlab.ui.control.Button
        t_elec_layout                  matlab.ui.control.Table
        BodyPanel                      matlab.ui.container.Panel
        chamber_fem                    matlab.ui.control.NumericEditField
        FEMRefinementEditFieldLabel    matlab.ui.control.Label
        chamber_boxZ                   matlab.ui.control.NumericEditField
        ZEditFieldLabel                matlab.ui.control.Label
        chamber_boxY                   matlab.ui.control.NumericEditField
        YEditFieldLabel                matlab.ui.control.Label
        chamber_boxX                   matlab.ui.control.NumericEditField
        XEditFieldLabel                matlab.ui.control.Label
        dD_chamber_typ                 matlab.ui.control.DropDown
        TypDropDownLabel               matlab.ui.control.Label
        Panel_fmdl                     matlab.ui.container.Panel
        cB_show_mesh_quality           matlab.ui.control.CheckBox
        FEMdisplayoptionsPanel         matlab.ui.container.Panel
        sw_show_elec_nb                matlab.ui.control.Switch
        ElectrodesLabel                matlab.ui.control.Label
        sw_show_mesh                   matlab.ui.control.Switch
        MeshLabel                      matlab.ui.control.Label
        fmdl_name                      matlab.ui.control.EditField
        NameLabel_2                    matlab.ui.control.Label
        Panel_fmdl_solver_params       matlab.ui.container.Panel
        dD_fmdl_miscperm_sym           matlab.ui.control.DropDown
        Miscperm_symLabel              matlab.ui.control.Label
        dD_fmdl_systmat                matlab.ui.control.DropDown
        System_matLabel                matlab.ui.control.Label
        dD_fmdl_jac                    matlab.ui.control.DropDown
        JacobianLabel                  matlab.ui.control.Label
        dD_fmdl_solve                  matlab.ui.control.DropDown
        SolveLabel                     matlab.ui.control.Label
        pB_fmdl_Save                   matlab.ui.control.Button
        pB_fmdl_Load                   matlab.ui.control.Button
        pB_fmdl_Make                   matlab.ui.control.Button
        PatternTab                     matlab.ui.container.Tab
        select_inj_special_2           matlab.ui.control.EditField
        InjectionrangeLabel            matlab.ui.control.Label
        pB_pattern_Make_2              matlab.ui.control.Button
        Panel_pattern_param            matlab.ui.container.Panel
        pattern_option                 matlab.ui.control.TextArea
        OptionsLabel                   matlab.ui.control.Label
        pattern_meas_special           matlab.ui.control.EditField
        MeasuserpatternLabel           matlab.ui.control.Label
        dD_pattern_meas_typ            matlab.ui.control.DropDown
        MeasurementpatternLabel        matlab.ui.control.Label
        pattern_inj_special            matlab.ui.control.EditField
        InjuserpatternLabel            matlab.ui.control.Label
        dD_pattern_inj_type            matlab.ui.control.DropDown
        InjectionpatternLabel          matlab.ui.control.Label
        dD_pattern_func                matlab.ui.control.DropDown
        PatterningfunctionLabel        matlab.ui.control.Label
        pattern_amplitude              matlab.ui.control.NumericEditField
        AmplitudeALabel                matlab.ui.control.Label
        pattern_option_info            matlab.ui.control.TextArea
        dD_actual_inj_nb               matlab.ui.control.DropDown
        InjectionNrDropDownLabel       matlab.ui.control.Label
        cB_pattern_all_display         matlab.ui.control.CheckBox
        cB_pattern_own_display         matlab.ui.control.CheckBox
        cB_pattern_with_meas           matlab.ui.control.CheckBox
        cB_pattern_animated            matlab.ui.control.CheckBox
        cB_pattern_hold                matlab.ui.control.CheckBox
        pB_pattern_Make                matlab.ui.control.Button
        MeasPatternPanel               matlab.ui.container.Panel
        MeasPatternTable               matlab.ui.control.Table
        pB_pattern_Display             matlab.ui.control.Button
        InjectionPatternPanel          matlab.ui.container.Panel
        InjectionPatternTable          matlab.ui.control.Table
        SolveForwardTab                matlab.ui.container.Tab
        save_fwd_sol                   matlab.ui.control.CheckBox
        ObjectsPanel                   matlab.ui.container.Panel
        medium_conduct                 matlab.ui.control.NumericEditField
        MediumconductivityEditFieldLabel  matlab.ui.control.Label
        RowMinusButton_CellPosition    matlab.ui.control.Button
        RowPlusButton_CellPosition     matlab.ui.control.Button
        t_objects                      matlab.ui.control.Table
        add_obj_inFEM                  matlab.ui.control.CheckBox
        SolvefowardproblemButton       matlab.ui.control.Button
        ax_sim_data                    matlab.ui.control.UIAxes
        SolveInverseTab                matlab.ui.container.Tab
        cB_meas_load                   matlab.ui.control.CheckBox
        SimGREITTable                  matlab.ui.control.Table
        SlicesforevalPanel             matlab.ui.container.Panel
        Slider_SliceZ                  matlab.ui.control.Slider
        SliceZHeightSliderLabel        matlab.ui.control.Label
        RowMinusButton_SimSlice        matlab.ui.control.Button
        RowPlusButton_SimSlice         matlab.ui.control.Button
        t_slices                       matlab.ui.control.Table
        Panel_imdl_solver_params       matlab.ui.container.Panel
        eF_imdl_name                   matlab.ui.control.EditField
        NameEditFieldLabel             matlab.ui.control.Label
        dD_imdl_jac_bkgnd_func         matlab.ui.control.DropDown
        JacobianbkgndfuncLabel         matlab.ui.control.Label
        dD_imdl_hyperparam_params      matlab.ui.control.DropDown
        HyperparameterparametersLabel  matlab.ui.control.Label
        dD_imdl_jac_bkgnd_val          matlab.ui.control.NumericEditField
        JacobianbkgndvalueLabel        matlab.ui.control.Label
        dD_imdl_hyperparam_val         matlab.ui.control.NumericEditField
        HyperparametervalueLabel       matlab.ui.control.Label
        dD_imdl_reconst_type           matlab.ui.control.DropDown
        ReconstructiontypeLabel        matlab.ui.control.Label
        dD_imdl_hyperparam_func        matlab.ui.control.DropDown
        HyperparameterfuncLabel        matlab.ui.control.Label
        dD_imdl_RtR_prior              matlab.ui.control.DropDown
        RtRpriorLabel                  matlab.ui.control.Label
        dD_imdl_solve                  matlab.ui.control.DropDown
        SolveLabel_2                   matlab.ui.control.Label
        Slider_imdl_transparency       matlab.ui.control.Slider
        TransparencyimgLabel           matlab.ui.control.Label
        pB_imdl_MakeSolve              matlab.ui.control.Button
        DatasetforAITab                matlab.ui.container.Tab
        pB_elec_layout_Add_3           matlab.ui.control.Button
        pB_dataset_sample_Show         matlab.ui.control.Button
        dataset_idx_samples_to_show    matlab.ui.control.EditField
        idxdatasamplesLabel            matlab.ui.control.Label
        pB_dataset_src_data_Show       matlab.ui.control.Button
        dataset_name                   matlab.ui.control.EditField
        pB_dataset_Load                matlab.ui.control.Button
        led_dataset_complete           matlab.ui.control.Lamp
        completeLampLabel              matlab.ui.control.Label
        t_user_entry                   matlab.ui.control.Table
        GenerateTrainingSamplesButton  matlab.ui.control.Button
        TypofrandominvsolverDropDown   matlab.ui.control.DropDown
        TypofrandominvsolverDropDownLabel  matlab.ui.control.Label
        pB_dataset_entry_Save          matlab.ui.control.Button
        GlobalTab                      matlab.ui.container.Tab
        chB_export2py                  matlab.ui.control.CheckBox
        pB_eit_env_Load                matlab.ui.control.Button
        pB_eit_env_Save                matlab.ui.control.Button
        eit_env_name                   matlab.ui.control.EditField
        eidors_path                    matlab.ui.control.EditField
        PathEIDORSEditFieldLabel_2     matlab.ui.control.Label
    end

    properties (Access = public)
        dir
        Figure
        CallingApp
        flag
        eit_env EIT_env
        eit_dataset EIT_dataset
    end

    methods (Access = public)
        
        function app = Init_values(app)

            %% Init Dropdown items
            app.dD_chamber_typ.Items =app.eit_env.setup.chamber.supported_forms();
            
            app.dD_pattern_func.Items=app.eit_env.setup.pattern.get_generating_func();
            % >>> the pattern list for inj and meas are in setDataGUI()
            
            app.dD_fmdl_solve.Items = app.eit_env.fwd_model.supported_solvers();
            app.dD_fmdl_jac.Items = app.eit_env.fwd_model.supported_jacobians();
            app.dD_fmdl_systmat.Items = app.eit_env.fwd_model.supported_sys_mats();
            app.dD_fmdl_miscperm_sym.Items = app.eit_env.fwd_model.supported_perm_sym();
            
            % Electrode Layouts
            elec_layout= EIT_elec_layout();% dummy
            app.t_elec_layout.ColumnFormat=elec_layout.get_format_4_gui();
            struct4gui=elec_layout.get_struct_4_gui();
            app.t_elec_layout.ColumnName= fieldnames(struct4gui);
            app.t_elec_layout.Data=[struct2cell(struct4gui)]';
            
            % Objects
            object= EIT_object(); %dummy
            app.t_objects.ColumnFormat = object.get_format_4_gui();
            struct4gui = object.get_struct_4_gui();
            app.t_objects.ColumnName= fieldnames(struct4gui);
            app.t_objects.Data =[struct2cell(struct4gui)]';
            
            % User entry
            user_e=UserEntry(); %dummy
            app.t_user_entry.ColumnFormat = user_e.get_format_4_gui();
            struct4gui = user_e.get_struct_4_gui();
            app.t_user_entry.ColumnName= replace(fieldnames(struct4gui(1)),'_',' ');
            app.t_user_entry.Data =[struct2cell(struct4gui)]';
            
            % Slices
            app.t_slices.Data= {inf inf 0; inf inf 0; inf inf 0};
            app.dD_chamber_typ_2ValueChanged()

        end

        function app= Init_Paths(app, currentPath)
            %% INIT Paths
            % set the used paths for Saving/Loading functions
            % app.dir.current is Define before the call of this function
            
%             path= mfilename('fullpath');
%             [fPath, fName, fExt] = fileparts(path);
            
            app.dir.current = currentPath;
            
            app.dir.src =path_join(app.dir.current, 'src');
            app.dir.default = path_join(app.dir.src, 'default');
            app.dir.local = path_join(app.dir.src, 'local');
            
            app.dir.env=path_join(app.dir.current, 'env');
            app.dir.sim=path_join(app.dir.env, 'sim');
            app.dir.rec=path_join(app.dir.env, 'sim');
            app.dir.fmdl=path_join(app.dir.env, 'fmdl');
            app.dir.chamber=path_join(app.dir.env, 'chamber');
            
            app.dir.output= path_join(app.dir.current, 'output');
            app.dir.user_entry= path_join(app.dir.output, 'user_entry');
            app.dir.dataset= path_join(app.dir.output, 'ai_datasets');

            % test the paths if not exist they are created
            create_path(app.dir);
            % add the paths to matlab
            add_2_matlabpath(app.dir)
            
        end

        function app= Start_EIDORS_ToolBox(app)
            t=Eidors_toolbox(app.dir.local);
            app.dir.EIDORS=t.local_path;
        end
        
        function plotSlices(app, img ,figName)
            
            if ~strcmp(img.type,'image')
                return;
            end
            
            levels= cell2mat(app.t_slices.Data);
            for i= 1:size(levels,1)
                img.calc_slices.levels= levels(i,:);
                figName= sprintf('Slice x = %d , y = %d , z = %d', img.calc_slices.levels);
                h= getCurrentFigure_with_figName(figName);
                h=show_slices(img,img.calc_slices.levels);
%                 show_cell(h,EIDORS.sim.iimg.calc_slices.levels,0)

                 %     param=eval_GREIT_fig_merit(EIDORS.sim.iimg, [[EIDORS.sim.cell(:).PosX]; [EIDORS.sim.cell(:).PosY]; [EIDORS.sim.cell(:).PosZ]; [EIDORS.sim.cell(:).Radius]]);
               %     title(sprintf('Amplitude: %0.3d, Pos Error: %0.3d Res: %0.3d, Shape: %0.3d, Ringing: %0.3d', param'));
                %
                %     param=param';
                %     EIDORS.sim.GREIT(i,1).AmpR=param(:,1);
                %     EIDORS.sim.GREIT(i,1).PosError=param(:,2);
                %     EIDORS.sim.GREIT(i,1).Res=param(:,3);
                %     EIDORS.sim.GREIT(i,1).ShapeDef=param(:,4);
                %     EIDORS.sim.GREIT(i,1).Ringing=param(:,5);
                %
                %     EIDORS.sim.RES_Yue=calc_Res_Yue();
            end
            
        end
        
        
        function plotFem(app, mdl, figName, colorBar)
            
            op=[0,0,0];
            
            if strcmp(mdl.type,'image') % tranparency for images
                mdl.fwd_model.show_fem.alpha_inhomogeneities=app.Slider_imdl_transparency.Value/100;
            end
            
            if logical(colorBar) % display colorbar
                op(1)= 1;
            end
            
            if app.sw_show_elec_nb.Value % display electrodes numbers
                op(2)=1.012;
            end
            
            h= getCurrentFigure_with_figName(figName);
            h= show_fem(mdl ,op);
            
            if ~app.sw_show_mesh.Value % show mesh 
                set(h,'EdgeColor','none');
            end
       
        end
        
        function app= SetDataGUI(app)
            
            % Slider slice in Z
            chamber_height= app.eit_env.setup.chamber.height();
            if chamber_height~=0
                app.Slider_SliceZ.Limits=[-chamber_height/2,chamber_height/2];
            end
            % Eidors starting path
            app.eidors_path.Value=app.dir.EIDORS;
            
            % Chamber
            app.chamber_name.Value    = app.eit_env.setup.chamber.name ;   
            app.dD_chamber_typ.Value     = app.eit_env.setup.chamber.form ;
            app.chamber_boxX.Value    = app.eit_env.setup.chamber.length();
            app.chamber_boxY.Value    = app.eit_env.setup.chamber.width();
            app.chamber_boxZ.Value    = app.eit_env.setup.chamber.height();
            app.chamber_fem.Value     = app.eit_env.setup.chamber.femRefinement;
            
            % Electrode layout
            struct4gui= app.eit_env.setup.get_elec_layout_4_gui();
            table_data=[];
            for i=1:length(struct4gui)
                table_data=[table_data; struct2cell(struct4gui(i))'];
            end
            app.t_elec_layout.Data=table_data;
            
            %fmdl solver
            fmdl= app.eit_env.fwd_model;
            app.fmdl_name.Value= fmdl.name;
            app.dD_fmdl_solve.Value= fmdl.solve;
            app.dD_fmdl_jac.Value= fmdl.jacobian;
            app.dD_fmdl_systmat.Value= fmdl.system_mat;
            app.dD_fmdl_miscperm_sym.Value= fmdl.misc.perm_sym;
            
            %pattern
            %update pattern choices 
            app.dD_pattern_inj_type.Items=app.eit_env.setup.pattern.get_patterns();
            app.dD_pattern_meas_typ.Items=app.eit_env.setup.pattern.get_patterns();
            app.dD_pattern_inj_type.ItemsData=app.eit_env.setup.pattern.get_patterns();
            app.dD_pattern_meas_typ.ItemsData=app.eit_env.setup.pattern.get_patterns();
            
            pattern= app.eit_env.setup.pattern;
            app.pattern_amplitude.Value = pattern.injAmplitude;
            app.dD_pattern_inj_type.Value = pattern.injType;
            app.pattern_inj_special.Value = pattern.injSpecial;
            app.dD_pattern_meas_typ.Value = pattern.measType;
            app.pattern_meas_special.Value =pattern.measSpecial;
            app.pattern_option.Value = pattern.patternOption;
            app.dD_pattern_func.Value = pattern.patternFunc;
            
            % update patterns table
            [inj, meas]=app.eit_env.fwd_model.extract_pattern_for_display();
            list_indx_inj= [1:size(inj,1)];
            
            app.InjectionPatternTable.Data = [list_indx_inj' inj];
 
            inj_indx= str2num(app.dD_actual_inj_nb.Value);
            app.MeasPatternTable.Data = meas(:,:,inj_indx);


            %Simulation
            % medium
            app.medium_conduct.Value = app.eit_env.sim.mediumConduct;
            % Electrode layout
            struct4gui= app.eit_env.sim.get_objects_4_gui();
            t=[];
            for i=1:length(struct4gui)
                t=[t; struct2cell(struct4gui(i))'];
            end
            app.t_objects.Data = t;
            
            %imdl solver
            imdl=app.eit_env.inv_model;
        
            app.eF_imdl_name.Value=imdl.name;
            app.dD_imdl_solve.Value = imdl.solve;
            app.dD_imdl_RtR_prior.Value =imdl.RtR_prior;
            
%             app.dD_imdl_hyperparam_func.Value = imdl.hyperparameter.func;
%             app.dD_imdl_hyperparam_params.Value = imdl.hyperparameter.parameters;
            app.dD_imdl_hyperparam_val.Value = imdl.hyperparameter.value;
%             app.dD_imdl_jac_bkgnd_func.Value = imdl.jacobian_bkgnd.func;
            app.dD_imdl_jac_bkgnd_val.Value = imdl.jacobian_bkgnd.value;
            app.dD_imdl_reconst_type.Value = imdl.reconst_type;
           
%             app.SimGREITTable.Data=[struct2cell(EIDORS.sim.GREIT)]';
%             app.SimGREITTable.ColumnName=fieldnames(EIDORS.sim.GREIT);

            %% Dataset
            % User entry
            user_e=app.eit_dataset.user_entry;
            app.t_user_entry.ColumnFormat = user_e.get_format_4_gui();
            struct4gui = user_e.get_struct_4_gui();
            app.t_user_entry.ColumnName= replace(fieldnames(struct4gui(1)),'_',' ');
            app.t_user_entry.Data =[struct2cell(struct4gui)]';
            
            if app.eit_dataset.is_done()
                app.led_dataset_complete.Color= [0,1,0];
            else
                app.led_dataset_complete.Color= [1,0,0];
            end
            
            app.dataset_name.Value=app.eit_dataset.name;

        end
        function [chamber, elec_layout, pattern, add_text] = get_eit_setup(app)
            % Chamber
            chamber = EIT_chamber( ...
                app.chamber_name.Value, ...
                [app.chamber_boxX.Value,app.chamber_boxY.Value, app.chamber_boxZ.Value], ...
                app.chamber_fem.Value, ...
                app.dD_chamber_typ.Value);
            
            % Electrode layout
            layout_data= cell2struct(app.t_elec_layout.Data, ...
                app.t_elec_layout.ColumnName, 2);
            for i=1:length(layout_data)
                elec_layout(i)= EIT_elec_layout(layout_data(i));           
            end
            %Pattern
            pattern= EIT_pattern( ...
                app.pattern_amplitude.Value, ...
                app.dD_pattern_inj_type.Value, ...
                app.pattern_inj_special.Value, ...
                app.dD_pattern_meas_typ.Value, ...
                app.pattern_meas_special.Value, ...
                app.pattern_option.Value, ...
                app.dD_pattern_func.Value);
           
            add_text='';
        end
        
        
        function [medium, objects] = get_sim_setup(app)
            %% set simulation Medium / Objects
            medium= app.medium_conduct.Value;
            objects_data(:) = cell2struct(app.t_objects.Data, ...app.flag
                app.t_objects.ColumnName, 2);
            
            for i=1:length(objects_data)
                objects(i)= EIT_object(objects_data(i));           
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, currentPath)
            %% Add the folder of THIS FILE and its subfolders
            app.Init_Paths(currentPath);
            
            app.eit_env=EIT_env();
            app.eit_dataset=EIT_dataset();
            
            app.Start_EIDORS_ToolBox();
            app.Init_values();
            
            
            app.SetDataGUI();
            
            % for debug purpose create fmdl and pattern!
            app.pB_fmdl_MakePushed();
            app.pB_pattern_MakePushed();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
             delete(app);
        end

        % Button pushed function: pB_eit_env_Save
        function pB_eit_env_SavePushed(app, event)
            %
            app.eit_env.name= app.eit_env_name.Value;
            t = datetime('now','TimeZone','local')
            app.eit_env.save(app.dir.env,[datestr(t, 'yyyyMMdd_HHmmss_') , app.eit_env.name, '_eit_env']);
            if app.chB_export2py.Value
                app.eit_env.make_mat_file4py(app.dir.env)
            end
            figure(app.UIFigure);
        end

        % Button pushed function: pB_eit_env_Load
        function pB_eit_env_LoadPushed(app, event)
            app.eit_env.update_from_file(app.dir.env)
            app.SetDataGUI();
            figure(app.UIFigure);
        end

        % Button pushed function: pB_fmdl_Make
        function pB_fmdl_MakePushed(app, event)
            
            [chamber, elec_layout, pattern, add_text] = app.get_eit_setup();
            
            % set the solver only when fwd model is created
            app.eit_env.fwd_model.set_solver( ...
                app.fmdl_name.Value, ...
                app.dD_fmdl_solve.Value, ...
                app.dD_fmdl_jac.Value, ...
                app.dD_fmdl_systmat.Value, ...
                app.dD_fmdl_miscperm_sym.Value);
            
            % app.FmdlGenerationFunctionDropDown.Value %
            % if needed transfer this to create_fwd_model        
            
            [fmdl, success]=app.eit_env.create_fwd_model(chamber, elec_layout, add_text);
            if success
                app.SetDataGUI();
                app.plotFem(app.eit_env.fwd_model.fmdl,'Mesh', 0);
            end
        end

        % Button pushed function: pB_fmdl_Save
        function pB_fmdl_SavePushed(app, event)
            app.eit_env.save(app.dir.fmdl, app.eit_env.fwd_model.name);
            figure(app.UIFigure);
        end

        % Button pushed function: pB_fmdl_Load
        function pB_fmdl_LoadPushed(app, event)
            [new_env, success, path]= app.eit_env.load(app.dir.fmdl,"");
            if success
                app.eit_env.fwd_model= new_env.fwd_model;
                app.SetDataGUI();
            end
            figure(app.UIFigure);
        end

        % Button pushed function: pB_chamber_Save
        function pB_chamber_SavePushed(app, event)
            app.eit_env.save(app.dir.chamber, app.eit_env.setup.chamber.name);
            figure(app.UIFigure);
        end

        % Button pushed function: pB_chamber_Load
        function pB_chamber_LoadPushed(app, event)
            % Refactored
            [new_env, success, path]= app.eit_env.load(app.dir.chamber,"");
            if success
                app.eit_env.setup.chamber= new_env.setup.chamber;
                app.SetDataGUI();
            end
            figure(app.UIFigure);
        end

        % Button pushed function: pB_pattern_Make
        function pB_pattern_MakePushed(app, event)
            [chamber, elec_layout, pattern, add_text] = app.get_eit_setup();
            
            app.eit_env.generate_pattern(pattern, app.cB_pattern_hold.Value);
            
             % set the list of injections
            [inj, meas]=app.eit_env.fwd_model.extract_pattern_for_display();
            list_indx_inj= [1:size(inj,1)];
            app.dD_actual_inj_nb.Items= string(num2cell(list_indx_inj));
            app.dD_actual_inj_nb.Value= "1"; %% reset to injection nr 1
            
            app.SetDataGUI(); 
            
        end

        % Button pushed function: pB_elec_layout_Add
        function pB_elec_layout_AddPushed(app, event)
            default_elec_layout= EIT_elec_layout();
            struct4gui= default_elec_layout.get_struct_4_gui();            
            app.t_elec_layout.Data=[app.t_elec_layout.Data; struct2cell(struct4gui)'];
        end

        % Button pushed function: pB_elec_layout_Del
        function pB_elec_layout_DelPushed(app, event)
            app.t_elec_layout.Data=app.t_elec_layout.Data(1:end-1,:);
        end

        % Value changed function: dD_actual_inj_nb
        function dD_actual_inj_nbValueChanged(app, event)
            app.SetDataGUI();
        end

        % Button pushed function: pB_pattern_Display
        function pB_pattern_DisplayPushed(app, event)

            fmdl=app.eit_env.fwd_model.fmdl;
            
            animated = app.cB_pattern_animated.Value; % value : 0 or 1
            with_meas = app.cB_pattern_with_meas.Value; % value : 0 or 1
            display_all=app.cB_pattern_all_display.Value; % value : 0 or 1
            
            if app.cB_pattern_own_display.Value==1
                Own_DisplayScript_Pattern(app.eit_env.fwd_model, display_all,animated,with_meas);
            else
                displayStimOption={'n','y','na','ya'};
                option= displayStimOption{with_meas+2*animated+1};
                show_stim_meas_pattern(fmdl,option);
            end

        end

        % Button pushed function: SolvefowardproblemButton
        function SolvefowardproblemButtonPushed(app, event)
            
            %% set simulation Medium / Objects
            [medium, objects] = app.get_sim_setup();
            app.eit_env.set_sim(medium, objects);
            
            %% solve foward
            app.eit_env.solve_fwd(app.add_obj_inFEM.Value);
            
            %%Plot 3D images
            figName= '3D Image of homogenious conductivity';
            app.plotFem(app.eit_env.sim.img_h, figName, 1);
            figName= '3D Image of inhomogenious conductivity';
            app.plotFem(app.eit_env.sim.img_ih, figName, 1);

%             show_cell(h,EIDORS.sim.iimg.calc_slices.levels,1)
            disp('Generate model with cell: Done!');
            
            
            %& Plot measurements
            axesHandle = app.ax_sim_data;%findobj('Tag', 'tagForYourAxes');
            ih = app.eit_env.sim.data_ih.meas;
            h = app.eit_env.sim.data_h.meas;
            plot(axesHandle,[ih, h, ih-h]);
            legend(axesHandle,{'ih', 'h', 'diff'}, "Location","northeast");
            
            if app.save_fwd_sol.Value==true
                name= [app.fmdl_name.Value '.mat'];
                file=path_join(app.dir.current, name);
                X= ih;
                X_ih= ih;
                X_h= h;
                eit_env= app.eit_env;
                [file, path] =uiputfile({'*.mat','*.m'}, 'Enter a filename for saving the foward solution', file);
                if file==0
                    return;
                end
                file=path_join(path, file);
                save(file, "X", "X_ih", "X_h", "eit_env", '-mat');
            end
            app.SetDataGUI();
        end

        % Button pushed function: RowPlusButton_CellPosition
        function RowPlusButton_CellPositions_Pushed(app, event)
            app.t_objects.Data=[app.t_objects.Data; {'Cell' '[0,0,0]' '[0.1, 0, 0]' '[0.1]'};];
        end

        % Button pushed function: RowMinusButton_CellPosition
        function RowMinusButton_CellPositions_Pushed(app, event)
            app.t_objects.Data=app.t_objects.Data(1:end-1,:);
        end

        % Value changed function: Slider_SliceZ
        function Slider_SliceZValueChanged(app, event)
            value = app.Slider_SliceZ.Value;
            app.t_slices.Data=[app.t_slices.Data; {inf, inf, value}];
            t= ['Slice at Z-height ' num2str(value, '%.3f') ' mm'];
            app.plotSlices(app.eit_env.rec.iimg ,t)
        end

        % Value changed function: Slider_imdl_transparency
        function Slider_imdl_transparencyValueChanged(app, event)
            figName= '3D Image of resolved inverse Problem';
            app.plotFem(app.eit_env.rec.iimg, figName, 1);
        end

        % Callback function
        function pB_sim_SavePushed(app, event)
            app.pB_eit_env_Save();
        end

        % Callback function
        function pB_sim_LoadPushed(app, event)
            app.pB_eit_env_Load();
        end

        % Value changed function: sw_show_mesh
        function sw_show_meshValueChanged(app, event)
            app.plotFem(app.eit_env.fwd_model.fmdl,'Mesh', 0);
        end

        % Value changed function: sw_show_elec_nb
        function sw_show_elec_nbValueChanged(app, event)
            app.plotFem(app.eit_env.fwd_model.fmdl,'Mesh', 0);
        end

        % Button pushed function: RowPlusButton_SimSlice
        function RowPlusButton_SimSlicePushed(app, event)
            app.t_slices.Data=[app.t_slices.Data; {Inf Inf 0}];
        end

        % Button pushed function: RowMinusButton_SimSlice
        function RowMinusButton_SimSlicePushed(app, event)
            app.t_slices.Data=app.t_slices.Data(1:end-1,:);
        end

        % Button pushed function: pB_imdl_MakeSolve
        function pB_imdl_MakeSolvePushed(app, event)
            app.eit_env.inv_model.set_inv_solver( ...
                '', ...
                app.dD_imdl_solve.Value, ...
                app.dD_imdl_RtR_prior.Value, ...
                '', ...
                app.dD_imdl_hyperparam_val.Value, ...
                app.dD_imdl_hyperparam_func.Value, ...
                app.dD_imdl_hyperparam_params.Value, ...
                app.dD_imdl_jac_bkgnd_val.Value, ...
                app.dD_imdl_jac_bkgnd_func.Value, ...
                app.dD_imdl_reconst_type.Value);
            
            % Solve
            path= '';
            if app.cB_meas_load.Value==1
                path= app.dir.current;
            end
            app.eit_env.solve_inv(path)
        
            %plots
            
            figName= '3D Image of resolved inverse Problem';
            app.plotFem(app.eit_env.rec.iimg, figName, 1);
            
%             show_cell(h,EIDORS.sim.levels,1)
            
             app.plotSlices(app.eit_env.rec.iimg ,figName)

        end

        % Value changed function: dD_pattern_func
        function dD_pattern_funcValueChanged(app, event)
            % set the pattren function choosen
            app.eit_env.setup.pattern.init_pattern_func(app.dD_pattern_func.Value);
            app.SetDataGUI();
        end

        % Button pushed function: pB_dataset_entry_Save
        function pB_dataset_entry_SavePushed(app, event)

            user_entries= cell2struct(app.t_user_entry.Data, ...
                replace(app.t_user_entry.ColumnName, ' ', '_'), 2);
        
            d=EIT_dataset();
            d.update(app.eit_env); % save all env in dataset
            
            for i=1%:length(user_entries)
                d.user_entry= UserEntry(user_entries(i));
                name= [d.user_entry.name '_user_entry.mat'];
                d.save(app.dir.user_entry, name);
            end
            app.eit_dataset=d;
            app.SetDataGUI();
        end

        % Button pushed function: GenerateTrainingSamplesButton
        function GenerateTrainingSamplesButtonPushed(app, event)
            d= EIT_dataset;          
            d.update_from_file(app.dir.user_entry);
            d.generate(app.dir.dataset);
        end

        % Button pushed function: pB_dataset_Load
        function pB_dataset_LoadPushed(app, event)
            app.eit_dataset.update_from_file(app.dir.dataset)
            app.SetDataGUI();
        end

        % Button pushed function: pB_dataset_src_data_Show
        function pB_dataset_src_data_ShowButtonPushed(app, event)
            idx=str2num_array(app.dataset_idx_samples_to_show.Value);
            [data, idx_data]= app.eit_dataset.load_data('src_data', idx)
            for i= 1:length(idx_data)
                data(i).reset_fmdl(app.eit_dataset.fwd_model.fmdl)
            end
            
            for i= 1:length(idx_data)
                img = data(i).img_ih;
                meas = data(i).data_ih.meas;
                
                subplot(length(idx_data),2,(i-1)*2+1)
                title(['Conduct Sample# ' num2str(idx_data(i))]);
                show_fem(img,[1,1,0]);
                
                subplot(length(idx_data),2,i*2)
                title(['Voltages Sample# ' num2str(idx_data(i))]);
                plot(meas)
            end 
            
            
        end

        % Button pushed function: pB_dataset_sample_Show
        function pB_dataset_sample_ShowButtonPushed(app, event)
            idx=str2num_array(app.dataset_idx_samples_to_show.Value);
            samples= app.eit_dataset.load_data('samples', idx)
        end

        % Button pushed function: pB_pattern_Make_2
        function pB_pattern_Make_2ButtonPushed(app, event)
            inj_range= str2num_array(app.select_inj_special_2.Value)
            app.eit_env.fwd_model.stimulation=app.eit_env.fwd_model.stimulation(inj_range)
            
             % set the list of injections
            [inj, meas]=app.eit_env.fwd_model.extract_pattern_for_display();
            list_indx_inj= [1:size(inj,1)];
            app.dD_actual_inj_nb.Items= string(num2cell(list_indx_inj));
            app.dD_actual_inj_nb.Value= "1"; %% reset to injection nr 1
            app.SetDataGUI(); 
        end

        % Value changed function: dD_chamber_typ_2
        function dD_chamber_typ_2ValueChanged(app, event)
            value = app.dD_chamber_typ_2.Value;
            voltage = '[V]';
            length= '[m]';
            current= '[A]';
            conduct= '[S/m]';            
            if strcmp(value,'2')
                length= '[mm]';
                current= '[mA]';
                conduct= '[mS/mm]';
            end
            
            app.XEditFieldLabel.Text= ['X ' length];
            app.YEditFieldLabel.Text= ['Y ' length];
            app.ZEditFieldLabel.Text= ['Z ' length];
            app.FEMRefinementEditFieldLabel.Text  = ['FEMRefinement ' length];
            app.AmplitudeALabel.Text = ['Amplitude ' current];
            app.MediumconductivityEditFieldLabel.Text = ['Medium conductivity ' conduct];


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ForwardmodelTab = uitab(app.TabGroup);
            app.ForwardmodelTab.Title = 'Forward model';
            app.Panel_fmdl = uipanel(app.ForwardmodelTab);
            app.Panel_fmdl.Title = 'Fwd model';
            app.pB_fmdl_Make = uibutton(app.Panel_fmdl, 'push');
            app.pB_fmdl_Make.ButtonPushedFcn = createCallbackFcn(app, @pB_fmdl_MakePushed, true);
            app.pB_fmdl_Make.Text = 'Make fwd model';
            app.pB_fmdl_Load = uibutton(app.Panel_fmdl, 'push');
            app.pB_fmdl_Load.ButtonPushedFcn = createCallbackFcn(app, @pB_fmdl_LoadPushed, true);
            app.pB_fmdl_Load.Text = 'Load';
            app.pB_fmdl_Save = uibutton(app.Panel_fmdl, 'push');
            app.pB_fmdl_Save.ButtonPushedFcn = createCallbackFcn(app, @pB_fmdl_SavePushed, true);
            app.pB_fmdl_Save.Text = 'Save';
            app.Panel_fmdl_solver_params = uipanel(app.Panel_fmdl);
            app.Panel_fmdl_solver_params.Title = 'Solving parameters';
            app.SolveLabel = uilabel(app.Panel_fmdl_solver_params);
            app.SolveLabel.Text = 'Solve';
            app.dD_fmdl_solve = uidropdown(app.Panel_fmdl_solver_params);
            app.dD_fmdl_solve.Items = {'eidors_default', 'fwd_solve_1st_order', 'aa_fwd_solve'};
            app.dD_fmdl_solve.Value = 'eidors_default';
            app.JacobianLabel = uilabel(app.Panel_fmdl_solver_params);
            app.JacobianLabel.Text = 'Jacobian';
            app.dD_fmdl_jac = uidropdown(app.Panel_fmdl_solver_params);
            app.dD_fmdl_jac.Items = {'eidors_default', 'jacobian_adjoint', 'aa_calc_jacobian', ''};
            app.dD_fmdl_jac.Value = 'eidors_default';
            app.System_matLabel = uilabel(app.Panel_fmdl_solver_params);
            app.System_matLabel.Text = 'System_mat';
            app.dD_fmdl_systmat = uidropdown(app.Panel_fmdl_solver_params);
            app.dD_fmdl_systmat.Items = {'eidors_default', 'system_mat_1st_order', 'aa_calc_system_mat', ''};
            app.dD_fmdl_systmat.Value = 'eidors_default';
            app.Miscperm_symLabel = uilabel(app.Panel_fmdl_solver_params);
            app.Miscperm_symLabel.Text = 'Misc.perm_sym';
            app.dD_fmdl_miscperm_sym = uidropdown(app.Panel_fmdl_solver_params);
            app.dD_fmdl_miscperm_sym.Items = {'''{n}'''};
            app.dD_fmdl_miscperm_sym.Value = '''{n}''';
            app.NameLabel_2 = uilabel(app.Panel_fmdl);
            app.NameLabel_2.Text = 'Name';
            app.fmdl_name = uieditfield(app.Panel_fmdl, 'text');
            app.fmdl_name.Value = 'fwd_model_defaultname';
            app.FEMdisplayoptionsPanel = uipanel(app.Panel_fmdl);
            app.FEMdisplayoptionsPanel.Title = 'FEM display options';
            app.MeshLabel = uilabel(app.FEMdisplayoptionsPanel);
            app.MeshLabel.Text = 'Mesh';
            app.sw_show_mesh = uiswitch(app.FEMdisplayoptionsPanel, 'slider');
            app.sw_show_mesh.Items = {'Show', 'Hide'};
            app.sw_show_mesh.ItemsData = [1 0];
            app.sw_show_mesh.ValueChangedFcn = createCallbackFcn(app, @sw_show_meshValueChanged, true);
            app.sw_show_mesh.Value = 1;
            app.ElectrodesLabel = uilabel(app.FEMdisplayoptionsPanel);
            app.ElectrodesLabel.Text = 'Electrodes #';
            app.sw_show_elec_nb = uiswitch(app.FEMdisplayoptionsPanel, 'slider');
            app.sw_show_elec_nb.Items = {'Show', 'Hide'};
            app.sw_show_elec_nb.ItemsData = [1 0];
            app.sw_show_elec_nb.ValueChangedFcn = createCallbackFcn(app, @sw_show_elec_nbValueChanged, true);
            app.sw_show_elec_nb.Value = 1;
            app.cB_show_mesh_quality = uicheckbox(app.Panel_fmdl);
            app.cB_show_mesh_quality.Enable = 'off';
            app.cB_show_mesh_quality.Text = 'show mesh quality';
            app.ChamberdesignPanel = uipanel(app.ForwardmodelTab);
            app.ChamberdesignPanel.Title = 'Chamber design';
            app.BodyPanel = uipanel(app.ChamberdesignPanel);
            app.BodyPanel.Title = 'Body';
            app.TypDropDownLabel = uilabel(app.BodyPanel);
            app.TypDropDownLabel.Text = 'Typ';
            app.dD_chamber_typ = uidropdown(app.BodyPanel);
            app.dD_chamber_typ.Items = {'Cylinder', 'Cubic', '2D_Circ', ''};
            app.dD_chamber_typ.Value = 'Cylinder';
            app.XEditFieldLabel = uilabel(app.BodyPanel);
            app.XEditFieldLabel.Text = 'X';
            app.chamber_boxX = uieditfield(app.BodyPanel, 'numeric');
            app.chamber_boxX.Value = 1;
            app.YEditFieldLabel = uilabel(app.BodyPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.chamber_boxY = uieditfield(app.BodyPanel, 'numeric');
            app.ZEditFieldLabel = uilabel(app.BodyPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.chamber_boxZ = uieditfield(app.BodyPanel, 'numeric');
            app.chamber_boxZ.Value = 1;
            app.FEMRefinementEditFieldLabel = uilabel(app.BodyPanel);
            app.FEMRefinementEditFieldLabel.Text = 'FEM Refinement';
            app.chamber_fem = uieditfield(app.BodyPanel, 'numeric');
            app.chamber_fem.Value = 0.3;
            app.electrodes = uipanel(app.ChamberdesignPanel);
            app.electrodes.Title = 'Electrode layouts';
            app.t_elec_layout = uitable(app.electrodes);
            app.t_elec_layout.ColumnName = {'Design'; 'Diameter'; 'Number'; 'Position'; 'Form'; 'Diameter_Width'; 'Height'; ''};
            app.t_elec_layout.RowName = {};
            app.t_elec_layout.ColumnEditable = true;
            app.pB_elec_layout_Add = uibutton(app.electrodes, 'push');
            app.pB_elec_layout_Add.ButtonPushedFcn = createCallbackFcn(app, @pB_elec_layout_AddPushed, true);
            app.pB_elec_layout_Add.Tooltip = {'add elec layout'};
            app.pB_elec_layout_Add.Text = '+';
            app.pB_elec_layout_Del = uibutton(app.electrodes, 'push');
            app.pB_elec_layout_Del.ButtonPushedFcn = createCallbackFcn(app, @pB_elec_layout_DelPushed, true);
            app.pB_elec_layout_Del.Tooltip = {'delete last elec layout'};
            app.pB_elec_layout_Del.Text = '-';
            app.pB_elec_layout_Add_2 = uibutton(app.electrodes, 'push');
            app.pB_elec_layout_Add_2.Tooltip = {'For "array"-layout the number can be: '; '- an integer > square design '; '- a double to specify the number of rows and colums  e.g. Number = 5.02  >> nb rows (in x) = 5; nb columns (in y) = 2'};
            app.pB_elec_layout_Add_2.Text = 'Readme';
            app.pB_chamber_Load = uibutton(app.ChamberdesignPanel, 'push');
            app.pB_chamber_Load.ButtonPushedFcn = createCallbackFcn(app, @pB_chamber_LoadPushed, true);
            app.pB_chamber_Load.Tooltip = {'load a chamber design previously saved'};
            app.pB_chamber_Load.Text = 'Load';
            app.pB_chamber_Save = uibutton(app.ChamberdesignPanel, 'push');
            app.pB_chamber_Save.ButtonPushedFcn = createCallbackFcn(app, @pB_chamber_SavePushed, true);
            app.pB_chamber_Save.Tooltip = {'save a chamber design'};
            app.pB_chamber_Save.Text = 'Save';
            app.NameLabel = uilabel(app.ChamberdesignPanel);
            app.NameLabel.Text = 'Name';
            app.chamber_name = uieditfield(app.ChamberdesignPanel, 'text');
            app.chamber_name.Value = 'ChamberDesign_defaultname';
            app.PatternTab = uitab(app.TabGroup);
            app.PatternTab.Title = 'Pattern';
            app.InjectionPatternPanel = uipanel(app.PatternTab);
            app.InjectionPatternPanel.Title = 'Injection Pattern';
            app.InjectionPatternTable = uitable(app.InjectionPatternPanel);
            app.InjectionPatternTable.ColumnName = {'nr'; 'Inj-'; 'Inj+'};
            app.InjectionPatternTable.RowName = {};
            app.pB_pattern_Display = uibutton(app.PatternTab, 'push');
            app.pB_pattern_Display.ButtonPushedFcn = createCallbackFcn(app, @pB_pattern_DisplayPushed, true);
            app.pB_pattern_Display.Text = 'Display Injection Pattern';
            app.MeasPatternPanel = uipanel(app.PatternTab);
            app.MeasPatternPanel.Title = 'Meas Pattern';
            app.MeasPatternTable = uitable(app.MeasPatternPanel);
            app.MeasPatternTable.ColumnName = {'Meas -'; 'Meas +'};
            app.MeasPatternTable.RowName = {};
            app.pB_pattern_Make = uibutton(app.PatternTab, 'push');
            app.pB_pattern_Make.ButtonPushedFcn = createCallbackFcn(app, @pB_pattern_MakePushed, true);
            app.pB_pattern_Make.Text = 'Make pattern';
            app.cB_pattern_hold = uicheckbox(app.PatternTab);
            app.cB_pattern_hold.Text = 'hold existing Injection pattern';
            app.cB_pattern_animated = uicheckbox(app.PatternTab);
            app.cB_pattern_animated.Text = 'Animated';
            app.cB_pattern_with_meas = uicheckbox(app.PatternTab);
            app.cB_pattern_with_meas.Text = 'with meas. Pattern';
            app.cB_pattern_own_display = uicheckbox(app.PatternTab);
            app.cB_pattern_own_display.Text = 'Own_DisplayScript';
            app.cB_pattern_all_display = uicheckbox(app.PatternTab);
            app.cB_pattern_all_display.Text = 'all display';
            app.InjectionNrDropDownLabel = uilabel(app.PatternTab);
            app.InjectionNrDropDownLabel.Text = 'Injection Nr.';
            app.dD_actual_inj_nb = uidropdown(app.PatternTab);
            app.dD_actual_inj_nb.Items = {'1', ''};
            app.dD_actual_inj_nb.ValueChangedFcn = createCallbackFcn(app, @dD_actual_inj_nbValueChanged, true);
            app.dD_actual_inj_nb.Value = '1';
            app.Panel_pattern_param = uipanel(app.PatternTab);
            app.Panel_pattern_param.Title = 'Pattern parameter';
            app.pattern_option_info = uitextarea(app.Panel_pattern_param);
            app.pattern_option_info.Editable = 'off';
            app.pattern_option_info.Value = {'if contradictory options are specified, only the last applies'; '%       ''channel_voltages'''; '%       -> get only the voltages at electrodes'; '%      ''no_meas_current'' / ''meas_current'''; '%         -> do / don''t make mesurements on current carrying electrodes'; '%         -> to not make measurements on the electrodes next to the current carrying,'; '%            use ''no_meas_current_next1'''; '%         -> to not make measurements on the two electrodes next to the current carrying,'; '%            use ''no_meas_current_next2'''; '%         -> to not make measurements on the three electrodes next to the current carrying,'; '%            use ''no_meas_current_next3'''; '%      ''rotate_meas'' / ''no_rotate_meas'''; '%         -> do / don''t rotate measurements with stimulation pattern'; '%      ''do_redundant'' / ''no_redundant'''; '%         -> do / don''t make reciprocally redundant measures'; '%      ''balance_inj'' / ''no_balance_inj'''; '%         -> do / don''t draw current from all electrodes so total'; '%            injection is zero (useful for mono patterns)'; '%      ''balance_meas'' / ''no_balance_meas'''; '%         -> do / don''t subtrant measurement from all electrodes so total'; '%            average measurement is zero (useful for mono patterns)'};
            app.AmplitudeALabel = uilabel(app.Panel_pattern_param);
            app.AmplitudeALabel.Text = 'Amplitude [A]';
            app.pattern_amplitude = uieditfield(app.Panel_pattern_param, 'numeric');
            app.pattern_amplitude.Value = 1;
            app.PatterningfunctionLabel = uilabel(app.Panel_pattern_param);
            app.PatterningfunctionLabel.Text = 'Patterning function';
            app.dD_pattern_func = uidropdown(app.Panel_pattern_param);
            app.dD_pattern_func.Items = {'mk_stim_pattern(EIDORS)', 'mk_stim_pattern_Array', 'mk_stim_3Dpattern'};
            app.dD_pattern_func.ValueChangedFcn = createCallbackFcn(app, @dD_pattern_funcValueChanged, true);
            app.dD_pattern_func.Value = 'mk_stim_pattern(EIDORS)';
            app.InjectionpatternLabel = uilabel(app.Panel_pattern_param);
            app.InjectionpatternLabel.Text = 'Injection pattern';
            app.dD_pattern_inj_type = uidropdown(app.Panel_pattern_param);
            app.dD_pattern_inj_type.Items = {'adjacent (EIDORS)', 'opposite (EIDORS)', 'user defined (EIDORS)', 'array_ad_simple', 'array_ad_full', 'array_ad_line', 'array_op'};
            app.dD_pattern_inj_type.ItemsData = {'{ad}', '{op}', 'user defined', 'array_ad_simple', 'array_ad_full', 'array_ad_line', 'array_op', ''};
            app.dD_pattern_inj_type.Value = '{ad}';
            app.InjuserpatternLabel = uilabel(app.Panel_pattern_param);
            app.InjuserpatternLabel.Text = 'Inj. user pattern';
            app.pattern_inj_special = uieditfield(app.Panel_pattern_param, 'text');
            app.pattern_inj_special.Tooltip = {'First pattern is [x+1,y+1]'};
            app.pattern_inj_special.Value = '[1 2]';
            app.MeasurementpatternLabel = uilabel(app.Panel_pattern_param);
            app.MeasurementpatternLabel.Text = 'Measurement pattern';
            app.dD_pattern_meas_typ = uidropdown(app.Panel_pattern_param);
            app.dD_pattern_meas_typ.Items = {'adjacent (EIDORS)', 'opposite (EIDORS)', 'user defined (EIDORS)', 'array_ad_simple', 'array_ad_full', 'array_ad_line', 'array_op', ''};
            app.dD_pattern_meas_typ.ItemsData = {'{ad}', '{op}', 'user defined', 'array_ad_simple', 'array_ad_full', 'array_ad_line', 'array_op', ''};
            app.dD_pattern_meas_typ.Value = '{ad}';
            app.MeasuserpatternLabel = uilabel(app.Panel_pattern_param);
            app.MeasuserpatternLabel.Text = 'Meas. user pattern';
            app.pattern_meas_special = uieditfield(app.Panel_pattern_param, 'text');
            app.pattern_meas_special.Tooltip = {'First pattern is [x+1,y+1]'};
            app.pattern_meas_special.Value = '[1 2]';
            app.OptionsLabel = uilabel(app.Panel_pattern_param);
            app.OptionsLabel.Text = 'Options';
            app.pattern_option = uitextarea(app.Panel_pattern_param);
            app.pattern_option.Tooltip = {'if contradictory options are specified, only the last applies'; '%       ''channel_voltages'''; '%       -> get only the voltages at electrodes'; '%      ''no_meas_current'' / ''meas_current'''; '%         -> do / don''t make mesurements on current carrying electrodes'; '%         -> to not make measurements on the electrodes next to the current carrying,'; '%            use ''no_meas_current_next1'''; '%         -> to not make measurements on the two electrodes next to the current carrying,'; '%            use ''no_meas_current_next2'''; '%         -> to not make measurements on the three electrodes next to the current carrying,'; '%            use ''no_meas_current_next3'''; '%      ''rotate_meas'' / ''no_rotate_meas'''; '%         -> do / don''t rotate measurements with stimulation pattern'; '%      ''do_redundant'' / ''no_redundant'''; '%         -> do / don''t make reciprocally redundant measures'; '%      ''balance_inj'' / ''no_balance_inj'''; '%         -> do / don''t draw current from all electrodes so total'; '%            injection is zero (useful for mono patterns)'; '%      ''balance_meas'' / ''no_balance_meas'''; '%         -> do / don''t subtrant measurement from all electrodes so total'; '%            average measurement is zero (useful for mono patterns)'};
            app.pattern_option.Value = {'meas_current'};
            app.pB_pattern_Make_2 = uibutton(app.PatternTab, 'push');
            app.pB_pattern_Make_2.ButtonPushedFcn = createCallbackFcn(app, @pB_pattern_Make_2ButtonPushed, true);
            app.pB_pattern_Make_2.Text = 'Select injections';
            app.InjectionrangeLabel = uilabel(app.PatternTab);
            app.InjectionrangeLabel.Text = 'Injection range';
            app.select_inj_special_2 = uieditfield(app.PatternTab, 'text');
            app.select_inj_special_2.Tooltip = {'Enet a range:'; '- [1,2,3]'; '- [1:10]'; '- [1,2,5:10]'};
            app.select_inj_special_2.Value = '[1 2]';
            app.SolveForwardTab = uitab(app.TabGroup);
            app.SolveForwardTab.Title = 'Solve Forward';
            app.ax_sim_data = uiaxes(app.SolveForwardTab);
            title(app.ax_sim_data, 'Title')
            xlabel(app.ax_sim_data, 'X')
            ylabel(app.ax_sim_data, 'Y')
            zlabel(app.ax_sim_data, 'Z')
            app.SolvefowardproblemButton = uibutton(app.SolveForwardTab, 'push');
            app.SolvefowardproblemButton.ButtonPushedFcn = createCallbackFcn(app, @SolvefowardproblemButtonPushed, true);
            app.SolvefowardproblemButton.Tooltip = {'Solve the forward problem with the previously deifine forward model!'};
            app.SolvefowardproblemButton.Text = 'Solve foward problem';
            app.add_obj_inFEM = uicheckbox(app.SolveForwardTab);
            app.add_obj_inFEM.Text = 'Add object in FEM';
            app.ObjectsPanel = uipanel(app.SolveForwardTab);
            app.ObjectsPanel.Title = 'Objects';
            app.t_objects = uitable(app.ObjectsPanel);
            app.t_objects.ColumnName = {'PosX'; 'PosY'; 'PosZ'; 'Radius'};
            app.t_objects.RowName = {};
            app.t_objects.ColumnEditable = true;
            app.RowPlusButton_CellPosition = uibutton(app.ObjectsPanel, 'push');
            app.RowPlusButton_CellPosition.ButtonPushedFcn = createCallbackFcn(app, @RowPlusButton_CellPositions_Pushed, true);
            app.RowPlusButton_CellPosition.Tooltip = {'add an object'};
            app.RowPlusButton_CellPosition.Text = '+';
            app.RowMinusButton_CellPosition = uibutton(app.ObjectsPanel, 'push');
            app.RowMinusButton_CellPosition.ButtonPushedFcn = createCallbackFcn(app, @RowMinusButton_CellPositions_Pushed, true);
            app.RowMinusButton_CellPosition.Tooltip = {'delete last object'};
            app.RowMinusButton_CellPosition.Text = '-';
            app.MediumconductivityEditFieldLabel = uilabel(app.ObjectsPanel);
            app.MediumconductivityEditFieldLabel.Text = 'Medium conductivity';
            app.medium_conduct = uieditfield(app.ObjectsPanel, 'numeric');
            app.medium_conduct.Tooltip = {'Medum conductivity in the chamber in ...'; ''};
            app.medium_conduct.Value = 1;
            app.save_fwd_sol = uicheckbox(app.SolveForwardTab);
            app.save_fwd_sol.Tooltip = {'save the simulated measurements in a .mat-file'; 'X_ih and X_h'};
            app.save_fwd_sol.Text = 'Save foward solution';
            app.SolveInverseTab = uitab(app.TabGroup);
            app.SolveInverseTab.Title = 'Solve Inverse';
            app.pB_imdl_MakeSolve = uibutton(app.SolveInverseTab, 'push');
            app.pB_imdl_MakeSolve.ButtonPushedFcn = createCallbackFcn(app, @pB_imdl_MakeSolvePushed, true);
            app.pB_imdl_MakeSolve.Text = 'Make imdl and solve';
            app.TransparencyimgLabel = uilabel(app.SolveInverseTab);
            app.TransparencyimgLabel.Text = 'Transparency img';
            app.Slider_imdl_transparency = uislider(app.SolveInverseTab);
            app.Slider_imdl_transparency.ValueChangedFcn = createCallbackFcn(app, @Slider_imdl_transparencyValueChanged, true);
            app.Slider_imdl_transparency.Value = 100;
            app.Panel_imdl_solver_params = uipanel(app.SolveInverseTab);
            app.Panel_imdl_solver_params.Title = 'Imdl solver parameters';
            app.SolveLabel_2 = uilabel(app.Panel_imdl_solver_params);
            app.SolveLabel_2.Text = 'Solve';
            app.dD_imdl_solve = uidropdown(app.Panel_imdl_solver_params);
            app.dD_imdl_solve.Items = {'eidors_default', 'inv_solve_TSVD', 'inv_solve_TV_irls', 'inv_solve_TV_pdipm', 'inv_solve_abs_GN_constrain', 'inv_solve_abs_GN_prior', 'inv_solve_abs_annealingMetropolis_params', 'inv_solve_abs_annealingSimplex_params', 'inv_solve_abs_pdipm', 'inv_solve_backproj', 'inv_solve_cg', 'inv_solve_conj_grad', 'inv_solve_core', 'inv_solve_diff_GN_one_step', 'inv_solve_diff_kalman', 'inv_solve_diff_pdipm', 'inv_solve_gn', 'inv_solve_mcmc', 'inv_solve_time_prior', 'inv_solve_trunc_iterative '};
            app.dD_imdl_solve.Value = 'eidors_default';
            app.RtRpriorLabel = uilabel(app.Panel_imdl_solver_params);
            app.RtRpriorLabel.Text = 'RtR prior';
            app.dD_imdl_RtR_prior = uidropdown(app.Panel_imdl_solver_params);
            app.dD_imdl_RtR_prior.Items = {'eidors_default', 'prior_TV', 'prior_covar', 'prior_exponential_covar', 'prior_gaussian_HPF', 'prior_gaussian_likelihood', 'prior_laplace', 'prior_laplace_old', 'prior_movement', 'prior_noser', 'prior_tikhonov', 'prior_time_smooth', ''};
            app.dD_imdl_RtR_prior.Value = 'eidors_default';
            app.HyperparameterfuncLabel = uilabel(app.Panel_imdl_solver_params);
            app.HyperparameterfuncLabel.Enable = 'off';
            app.HyperparameterfuncLabel.Text = 'Hyperparameter func';
            app.dD_imdl_hyperparam_func = uidropdown(app.Panel_imdl_solver_params);
            app.dD_imdl_hyperparam_func.Items = {'NOT USED', 'aa_calc_noise_figure', ''};
            app.dD_imdl_hyperparam_func.Enable = 'off';
            app.dD_imdl_hyperparam_func.Value = 'NOT USED';
            app.ReconstructiontypeLabel = uilabel(app.Panel_imdl_solver_params);
            app.ReconstructiontypeLabel.Text = 'Reconstruction type';
            app.dD_imdl_reconst_type = uidropdown(app.Panel_imdl_solver_params);
            app.dD_imdl_reconst_type.Items = {'difference', 'static'};
            app.dD_imdl_reconst_type.Value = 'difference';
            app.HyperparametervalueLabel = uilabel(app.Panel_imdl_solver_params);
            app.HyperparametervalueLabel.Text = 'Hyperparameter value';
            app.dD_imdl_hyperparam_val = uieditfield(app.Panel_imdl_solver_params, 'numeric');
            app.dD_imdl_hyperparam_val.Value = 0.01;
            app.JacobianbkgndvalueLabel = uilabel(app.Panel_imdl_solver_params);
            app.JacobianbkgndvalueLabel.Text = 'Jacobian bkgnd value';
            app.dD_imdl_jac_bkgnd_val = uieditfield(app.Panel_imdl_solver_params, 'numeric');
            app.dD_imdl_jac_bkgnd_val.Value = 1;
            app.HyperparameterparametersLabel = uilabel(app.Panel_imdl_solver_params);
            app.HyperparameterparametersLabel.Enable = 'off';
            app.HyperparameterparametersLabel.Text = 'Hyperparameter parameters';
            app.dD_imdl_hyperparam_params = uidropdown(app.Panel_imdl_solver_params);
            app.dD_imdl_hyperparam_params.Items = {'NOT USED', 'noise_figure', ''};
            app.dD_imdl_hyperparam_params.Enable = 'off';
            app.dD_imdl_hyperparam_params.Value = 'NOT USED';
            app.JacobianbkgndfuncLabel = uilabel(app.Panel_imdl_solver_params);
            app.JacobianbkgndfuncLabel.Enable = 'off';
            app.JacobianbkgndfuncLabel.Text = 'Jacobian bkgnd func';
            app.dD_imdl_jac_bkgnd_func = uidropdown(app.Panel_imdl_solver_params);
            app.dD_imdl_jac_bkgnd_func.Items = {'NOT USED'};
            app.dD_imdl_jac_bkgnd_func.Enable = 'off';
            app.dD_imdl_jac_bkgnd_func.Value = 'NOT USED';
            app.NameEditFieldLabel = uilabel(app.Panel_imdl_solver_params);
            app.NameEditFieldLabel.Text = 'Name';
            app.eF_imdl_name = uieditfield(app.Panel_imdl_solver_params, 'text');
            app.eF_imdl_name.Value = 'imdl_name';
            app.SlicesforevalPanel = uipanel(app.SolveInverseTab);
            app.SlicesforevalPanel.Title = 'Slices for eval';
            app.t_slices = uitable(app.SlicesforevalPanel);
            app.t_slices.ColumnName = {'x='; 'y='; 'z='};
            app.t_slices.RowName = {};
            app.t_slices.ColumnEditable = true;
            app.RowPlusButton_SimSlice = uibutton(app.SlicesforevalPanel, 'push');
            app.RowPlusButton_SimSlice.ButtonPushedFcn = createCallbackFcn(app, @RowPlusButton_SimSlicePushed, true);
            app.RowPlusButton_SimSlice.Text = '+';
            app.RowMinusButton_SimSlice = uibutton(app.SlicesforevalPanel, 'push');
            app.RowMinusButton_SimSlice.ButtonPushedFcn = createCallbackFcn(app, @RowMinusButton_SimSlicePushed, true);
            app.RowMinusButton_SimSlice.Text = '-';
            app.SliceZHeightSliderLabel = uilabel(app.SlicesforevalPanel);
            app.SliceZHeightSliderLabel.Text = 'Slice Z-Height';
            app.Slider_SliceZ = uislider(app.SlicesforevalPanel);
            app.Slider_SliceZ.ValueChangedFcn = createCallbackFcn(app, @Slider_SliceZValueChanged, true);
            app.Slider_SliceZ.Value = 50;
            app.SimGREITTable = uitable(app.SolveInverseTab);
            app.SimGREITTable.ColumnName = {'AmpR'; 'PosError'; 'Res'; 'ShapeDef'; 'Ringing'};
            app.SimGREITTable.RowName = {};
            app.SimGREITTable.ColumnEditable = true;
            app.cB_meas_load = uicheckbox(app.SolveInverseTab);
            app.cB_meas_load.Text = 'Load meas';
            app.DatasetforAITab = uitab(app.TabGroup);
            app.DatasetforAITab.Tooltip = {''};
            app.DatasetforAITab.Title = 'Dataset for AI';
            app.pB_dataset_entry_Save = uibutton(app.DatasetforAITab, 'push');
            app.pB_dataset_entry_Save.ButtonPushedFcn = createCallbackFcn(app, @pB_dataset_entry_SavePushed, true);
            app.pB_dataset_entry_Save.Text = 'Save dataset user_entry';
            app.TypofrandominvsolverDropDownLabel = uilabel(app.DatasetforAITab);
            app.TypofrandominvsolverDropDownLabel.Enable = 'off';
            app.TypofrandominvsolverDropDownLabel.Text = 'Typ of random invsolver';
            app.TypofrandominvsolverDropDown = uidropdown(app.DatasetforAITab);
            app.TypofrandominvsolverDropDown.Items = {'GN', 'TV'};
            app.TypofrandominvsolverDropDown.Enable = 'off';
            app.TypofrandominvsolverDropDown.Value = 'GN';
            app.GenerateTrainingSamplesButton = uibutton(app.DatasetforAITab, 'push');
            app.GenerateTrainingSamplesButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateTrainingSamplesButtonPushed, true);
            app.GenerateTrainingSamplesButton.Text = 'Generate Training Samples';
            app.t_user_entry = uitable(app.DatasetforAITab);
            app.t_user_entry.ColumnName = {'name'; 'num_training'; 'medium conduct'; 'object typ'; 'object n'; 'object dim'; 'object conduct'; 'SNR'; 'single data n'; 'samples size'};
            app.t_user_entry.RowName = {};
            app.t_user_entry.ColumnEditable = true;
            app.completeLampLabel = uilabel(app.DatasetforAITab);
            app.completeLampLabel.Text = 'complete';
            app.led_dataset_complete = uilamp(app.DatasetforAITab);
            app.pB_dataset_Load = uibutton(app.DatasetforAITab, 'push');
            app.pB_dataset_Load.ButtonPushedFcn = createCallbackFcn(app, @pB_dataset_LoadPushed, true);
            app.pB_dataset_Load.Text = 'Load dataset';
            app.dataset_name = uieditfield(app.DatasetforAITab, 'text');
            app.dataset_name.Value = 'Loaded_dataset_name';
            app.pB_dataset_src_data_Show = uibutton(app.DatasetforAITab, 'push');
            app.pB_dataset_src_data_Show.ButtonPushedFcn = createCallbackFcn(app, @pB_dataset_src_data_ShowButtonPushed, true);
            app.pB_dataset_src_data_Show.Text = 'Show src_data';
            app.idxdatasamplesLabel = uilabel(app.DatasetforAITab);
            app.idxdatasamplesLabel.Text = 'idx data/samples';
            app.dataset_idx_samples_to_show = uieditfield(app.DatasetforAITab, 'text');
            app.dataset_idx_samples_to_show.Value = '1,2,3';
            app.pB_dataset_sample_Show = uibutton(app.DatasetforAITab, 'push');
            app.pB_dataset_sample_Show.ButtonPushedFcn = createCallbackFcn(app, @pB_dataset_sample_ShowButtonPushed, true);
            app.pB_dataset_sample_Show.Enable = 'off';
            app.pB_dataset_sample_Show.Text = 'Show src_data';
            app.pB_elec_layout_Add_3 = uibutton(app.DatasetforAITab, 'push');
            app.pB_elec_layout_Add_3.Tooltip = {'name                = ''''   % EIT dataset name correspoding to the given parameters '; '        samplesAmount       = [];  % total samples amout/number'; '        mediumsConductRange = [];  % conductivity range of the medium, e.g. [0.1,0.3]       '; '        objectType          = []   % type of the randomly generated objects'; '        objectAmountRange   = [];  % amout range of the randomly generated objects in chamber, e.g. [5 10] from 5 to 10 objects'; '        objectDimRange      = [];  % Dimension range of the randomly generated objects (can be 2dimensional if multiple dimenseion are required), dim(i,:) = [minRange maxRange]'; '                                   % for sphere/cell [Rmin, Rmax],  for cylinder [Rmin, Rmax; (Lmin, Lmax)]'; '        objectConductRange  = [];  % layer conductivity range of the randomly generated objects'; '        SNR                 = [];  % noise ratio level in dB for generation of noisy samples'; '        samplesFileSize     = 1000 % nb of samples per samples batch files'; '        srcFileSize         = 250  % nb of single data per src batch files        mediumsConductRange = [];  % conductivity range of the medium, e.g. [0.1,0.3]       '; '        objectType          = []   % type of the randomly generated objects'; '        objectAmountRange   = [];  % amout range of the randomly generated objects in chamber, e.g. [5 10] from 5 to 10 objects'; '        objectDimRange      = [];  % Dimension range of the randomly generated objects (can be 2dimensional if multiple dimenseion are required), dim(i,:) = [minRange maxRange]'; '                                   % for sphere/cell [Rmin Rmax],  for cylinder [Rmin Rmax, (Lmin, Lmax)]'; '        objectConductRange  = [];  % layer conductivity range of the randomly generated objects'; '        SNR                 = [];  % noise ratio level in dB for generation of noisy samples'; '        samplesFileSize     = 1000 % nb of samples per samples batch files'; '        srcFileSize         = 250  % nb of single data per src batch files'};
            app.pB_elec_layout_Add_3.Text = 'Readme';
            app.GlobalTab = uitab(app.TabGroup);
            app.GlobalTab.Title = 'Global';
            app.PathEIDORSEditFieldLabel_2 = uilabel(app.GlobalTab);
            app.PathEIDORSEditFieldLabel_2.Text = 'Path EIDORS';
            app.eidors_path = uieditfield(app.GlobalTab, 'text');
            app.eit_env_name = uieditfield(app.GlobalTab, 'text');
            app.eit_env_name.Value = 'eit_env_defaultname';
            app.pB_eit_env_Save = uibutton(app.GlobalTab, 'push');
            app.pB_eit_env_Save.ButtonPushedFcn = createCallbackFcn(app, @pB_eit_env_SavePushed, true);
            app.pB_eit_env_Save.Tooltip = {'Save the complete environement '};
            app.pB_eit_env_Save.Text = 'Save environement';
            app.pB_eit_env_Load = uibutton(app.GlobalTab, 'push');
            app.pB_eit_env_Load.ButtonPushedFcn = createCallbackFcn(app, @pB_eit_env_LoadPushed, true);
            app.pB_eit_env_Load.Text = 'Load environement';
            app.chB_export2py = uicheckbox(app.GlobalTab);
            app.chB_export2py.Text = 'export 2py';
            app.Title = uitextarea(app.UIFigure);
            app.Title.Editable = 'off';
            app.Title.Value = {'Electrical impedance tomographie - Simulation Software'; 'based on the EIDORS toolbox'; 'Institute of Microtechnology - David Metz - V2.0'};
            app.UnitsLabel = uilabel(app.UIFigure);
            app.UnitsLabel.Text = 'Units';
            app.dD_chamber_typ_2 = uidropdown(app.UIFigure);
            app.dD_chamber_typ_2.Items = {'V, A, m, S/m', 'V, mA, mm, mS/mm'};
            app.dD_chamber_typ_2.ItemsData = {'1', '2'};
            app.dD_chamber_typ_2.ValueChangedFcn = createCallbackFcn(app, @dD_chamber_typ_2ValueChanged, true);
            app.dD_chamber_typ_2.Value = '2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FwdModel_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end